classdef GUI_2D_FDTD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUI                            matlab.ui.Figure
        Plot                           matlab.ui.control.UIAxes
        Dim_X                          matlab.ui.control.NumericEditField
        Dim_Y                          matlab.ui.control.NumericEditField
        DimensionxyLabel               matlab.ui.control.Label
        Scatter_X                      matlab.ui.control.NumericEditField
        RectangularScatterLowerLeftxyLabel  matlab.ui.control.Label
        Scatter_Y                      matlab.ui.control.NumericEditField
        PMLthicknessdxEditFieldLabel   matlab.ui.control.Label
        PML_Width                      matlab.ui.control.NumericEditField
        ShowLayoutButton               matlab.ui.control.Button
        FDTDstartButton                matlab.ui.control.Button
        PECScatterCheckBox             matlab.ui.control.CheckBox
        usePMLotherwiseusePECCheckBox  matlab.ui.control.CheckBox
        dxEditFieldLabel_2             matlab.ui.control.Label
        WriteParametersButton          matlab.ui.control.Button
        EzPlot                         matlab.ui.control.UIAxes
        SourceLocationxyLabel          matlab.ui.control.Label
        Source_X                       matlab.ui.control.NumericEditField
        Source_Y                       matlab.ui.control.NumericEditField
        RectangularScatterDimensionxyLabel  matlab.ui.control.Label
        ScatterDim_X                   matlab.ui.control.NumericEditField
        ScatterDim_Y                   matlab.ui.control.NumericEditField
        TargetLocationxyLabel          matlab.ui.control.Label
        Target_X                       matlab.ui.control.NumericEditField
        Target_Y                       matlab.ui.control.NumericEditField
        dxEditFieldLabel               matlab.ui.control.Label
        lambda                         matlab.ui.control.NumericEditField
        timestepsEditFieldLabel        matlab.ui.control.Label
        timestepsEditField             matlab.ui.control.NumericEditField
        dxEditField                    matlab.ui.control.NumericEditField
        SetParametersButton            matlab.ui.control.Button
        RescaleButton                  matlab.ui.control.Button
        ExportEzButton                 matlab.ui.control.Button
    end


    properties (Access = public)
        parameters
        useJoe_Yifan
        Ez
    end

    methods (Access = private)
    
        function setDisplay(app)
            app.Dim_X.Value = app.parameters.dimensionX;
            app.Dim_Y.Value = app.parameters.dimensionY;
            
            app.Source_X.Value = app.parameters.sourceLocationX;  
            app.Source_Y.Value = app.parameters.sourceLocationY; 
            
            app.PECScatterCheckBox.Value = app.parameters.PECScattFlag;
            app.Scatter_X.Value = app.parameters.PECScattLocationX;
            app.Scatter_Y.Value = app.parameters.PECScattLocationY;
            app.ScatterDim_X.Value = app.parameters.PECScattDimensionX;
            app.ScatterDim_Y.Value = app.parameters.PECScattDimensionY;
            
            app.usePMLotherwiseusePECCheckBox.Value = app.parameters.PMLFlag;
            app.PML_Width.Value = app.parameters.PMLthickness;
            
            app.dxEditField.Value = app.parameters.dx;
            app.lambda.Value = app.parameters.lambda;
            app.timestepsEditField.Value = app.parameters.time_step;
            
            app.Target_X.Value = app.parameters.targetPointX;
            app.Target_Y.Value = app.parameters.targetPointY;
        end
        
        
        function initParameters(app)
            %{
            app.parameters.dimensionX = 299;  
            app.parameters.dimensionY = 299; 
            
            app.parameters.sourceLocationX = 150;  
            app.parameters.sourceLocationY = 150; 
            app.parameters.sourceType = 0; % ignored
            
            app.parameters.PECScattFlag = 1; 
            app.parameters.PECScattLocationX = 70; % lower left corner
            app.parameters.PECScattLocationY = 70; 
            app.parameters.PECScattDimensionX = 30; 
            app.parameters.PECScattDimensionY = 30; 
            
            app.parameters.PMLFlag = 1; 
            app.parameters.PMLthickness = 25;
            
            app.parameters.dx = 1e-6;
            app.parameters.lambda = 20;
            app.parameters.time_step = 300;
            
            app.parameters.targetPointX = 130;
            app.parameters.targetPointY = 130;
            %}
            
            app.parameters.dimensionX = 199;  
            app.parameters.dimensionY = 159; 
            
            app.parameters.sourceLocationX = 100;  
            app.parameters.sourceLocationY = 80; 
            app.parameters.sourceType = 0; % ignored
            
            app.parameters.PECScattFlag = 1; 
            app.parameters.PECScattLocationX = 60; % lower left corner
            app.parameters.PECScattLocationY = 60; 
            app.parameters.PECScattDimensionX = 10; 
            app.parameters.PECScattDimensionY = 10; 
            
            app.parameters.PMLFlag = 1; 
            app.parameters.PMLthickness = 25;
            
            app.parameters.dx = 1e-6;
            app.parameters.lambda = 20;
            app.parameters.time_step = 300;
            
            app.parameters.targetPointX = 110;
            app.parameters.targetPointY = 90;
            
            initEzPlot(app);
        end
        
        function setParameters(app)
            % verify() may be just verified by judging from the layout 
            app.parameters.dimensionX = app.Dim_X.Value;
            app.parameters.dimensionY = app.Dim_Y.Value;
            
            app.parameters.sourceLocationX = app.Source_X.Value;  
            app.parameters.sourceLocationY = app.Source_Y.Value; 
            
            app.parameters.PECScattFlag       = app.PECScatterCheckBox.Value;
            app.parameters.PECScattLocationX  = app.Scatter_X.Value         ;
            app.parameters.PECScattLocationY  = app.Scatter_Y.Value         ;
            app.parameters.PECScattDimensionX = app.ScatterDim_X.Value      ;
            app.parameters.PECScattDimensionY = app.ScatterDim_Y.Value      ;
            
            app.parameters.PMLFlag = app.usePMLotherwiseusePECCheckBox.Value;
            app.parameters.PMLthickness = app.PML_Width.Value;
            
            app.parameters.dx = app.dxEditField.Value;
            app.parameters.lambda = app.lambda.Value;
            app.parameters.time_step = app.timestepsEditField.Value;
            
            app.parameters.targetPointY = app.Target_Y.Value;
            app.parameters.targetPointX = app.Target_X.Value;
            
            initEzPlot(app);
        end
        
        function convert_and_write(app)
            config = { ...
                int32(app.parameters.dimensionX), ...
                int32(app.parameters.dimensionY), ...
                ...
                int32(app.parameters.sourceLocationX), ...  
                int32(app.parameters.sourceLocationY), ... 
                ...
                int32(app.parameters.PECScattFlag), ...
                int32(app.parameters.PECScattLocationX), ...
                int32(app.parameters.PECScattLocationY), ...
                int32(app.parameters.PECScattDimensionX), ...
                int32(app.parameters.PECScattDimensionY), ...
                ...
                int32(app.parameters.PMLFlag), ...
                int32(app.parameters.PMLthickness), ...
                ...
                double(app.parameters.dx), ...
                int32(app.parameters.lambda), ...
                int32(app.parameters.time_step), ...
                ...
                int32(app.parameters.targetPointX), ...
                int32(app.parameters.targetPointY) };
            f = fopen('config.bin', 'wb');
            for i = 1 : numel(config)
                fwrite(f, config{i}, class(config{i}));
                % config{i}
                % class(config{i})
            end
            fclose(f);
        end
        
        function initEzPlot(app)
            
            cla(app.EzPlot,'reset');
            app.EzPlot.Title.String = sprintf('Ez @ grid point(%d,%d)',app.parameters.targetPointX, app.parameters.targetPointY);
            app.EzPlot.XLabel.String = 't /dt';
            % app.EzPlot.DataAspectRatioMode = 'auto';
            % app.EzPlot.PlotBoxAspectRatioMode = 'auto';
            app.EzPlot.XLim = [0, app.parameters.time_step];
            app.EzPlot.YLim = [-1, +1];
            % plot(app.EzPlot, [0,app.parameters.time_step], [0,0]);
            % app.EzPlot.YLim = [-1, +1];      
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initParameters(app);
            setDisplay(app);         
            GUI_ShowLayout(app.parameters, app.Plot);
            
            clc;
            
            app.useJoe_Yifan = 'Yifan';
        end

        % Button pushed function: FDTDstartButton
        function FDTDstartButtonPushed(app, event)
            setParameters(app);
            if strcmp(app.useJoe_Yifan,'Joe')
                app.Ez = GUI_plotMovie(app.parameters, app.Plot, app.EzPlot);
            end
            if strcmp(app.useJoe_Yifan,'Yifan')
                app.Ez = proj1_FDTDgradientPML_GUI(app.parameters, app.Plot, app.EzPlot);
            end
        end

        % Value changed function: PECScatterCheckBox
        function PECScatterCheckBoxValueChanged(app, event)
            value = app.PECScatterCheckBox.Value;
            % https://www.mathworks.com/help/matlab/ref/matlab.ui.control.editfield-properties.html#property_d119e1197711
            
            if value                
                app.Scatter_X.Enable = 'on';
                app.Scatter_Y.Enable = 'on';
                app.ScatterDim_X.Enable = 'on';
                app.ScatterDim_Y.Enable = 'on';
            else
                app.Scatter_X.Enable = 'off';
                app.Scatter_Y.Enable = 'off';
                app.ScatterDim_X.Enable = 'off';
                app.ScatterDim_Y.Enable = 'off';
            end
        end

        % Button pushed function: ShowLayoutButton
        function ShowLayoutButtonPushed(app, event)
            setParameters(app);
            GUI_ShowLayout(app.parameters, app.Plot);
        end

        % Button pushed function: WriteParametersButton
        function WriteParametersButtonPushed(app, event)
            setParameters(app);
            convert_and_write(app);
        end

        % Value changed function: usePMLotherwiseusePECCheckBox
        function usePMLotherwiseusePECCheckBoxValueChanged(app, event)
            value = app.usePMLotherwiseusePECCheckBox.Value;
         
            if value
                app.PML_Width.Enable = 'on';
            else
                app.PML_Width.Enable = 'off';
            end
        end

        % Button pushed function: SetParametersButton
        function SetParametersButtonPushed(app, event)
            setParameters(app);
        end

        % Button pushed function: RescaleButton
        function RescaleButtonPushed(app, event)
            app.EzPlot.YLimMode = 'auto';
        end

        % Button pushed function: ExportEzButton
        function ExportEzButtonPushed(app, event)
            assignin('base', 'Ez', app.Ez);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUI = uifigure;
            app.GUI.Name = '2D FDTD';
            app.Plot = uiaxes(app.GUI);
            title(app.Plot, 'Title')
            xlabel(app.Plot, 'X')
            ylabel(app.Plot, 'Y')
            app.Dim_X = uieditfield(app.GUI, 'numeric');
            app.Dim_Y = uieditfield(app.GUI, 'numeric');
            app.DimensionxyLabel = uilabel(app.GUI);
            app.DimensionxyLabel.Text = 'Dimension, x & y';
            app.Scatter_X = uieditfield(app.GUI, 'numeric');
            app.RectangularScatterLowerLeftxyLabel = uilabel(app.GUI);
            app.RectangularScatterLowerLeftxyLabel.Text = 'Rectangular Scatter Lower Left @ x & y';
            app.Scatter_Y = uieditfield(app.GUI, 'numeric');
            app.PMLthicknessdxEditFieldLabel = uilabel(app.GUI);
            app.PMLthicknessdxEditFieldLabel.Text = 'PML thickness /dx';
            app.PML_Width = uieditfield(app.GUI, 'numeric');
            app.ShowLayoutButton = uibutton(app.GUI, 'push');
            app.ShowLayoutButton.ButtonPushedFcn = createCallbackFcn(app, @ShowLayoutButtonPushed, true);
            app.ShowLayoutButton.Text = 'Show Layout';
            app.FDTDstartButton = uibutton(app.GUI, 'push');
            app.FDTDstartButton.ButtonPushedFcn = createCallbackFcn(app, @FDTDstartButtonPushed, true);
            app.FDTDstartButton.Text = 'FDTD start';
            app.PECScatterCheckBox = uicheckbox(app.GUI);
            app.PECScatterCheckBox.ValueChangedFcn = createCallbackFcn(app, @PECScatterCheckBoxValueChanged, true);
            app.PECScatterCheckBox.Text = 'PEC Scatter ?';
            app.usePMLotherwiseusePECCheckBox = uicheckbox(app.GUI);
            app.usePMLotherwiseusePECCheckBox.ValueChangedFcn = createCallbackFcn(app, @usePMLotherwiseusePECCheckBoxValueChanged, true);
            app.usePMLotherwiseusePECCheckBox.Text = 'use PML? (otherwise use PEC)';
            app.dxEditFieldLabel_2 = uilabel(app.GUI);
            app.dxEditFieldLabel_2.Text = 'dx';
            app.WriteParametersButton = uibutton(app.GUI, 'push');
            app.WriteParametersButton.ButtonPushedFcn = createCallbackFcn(app, @WriteParametersButtonPushed, true);
            app.WriteParametersButton.Text = 'Write Parameters';
            app.EzPlot = uiaxes(app.GUI);
            title(app.EzPlot, 'Title')
            xlabel(app.EzPlot, 'X')
            ylabel(app.EzPlot, 'Y')
            app.EzPlot.XLim = [0 1];
            app.EzPlot.XLimMode = 'manual';
            app.EzPlot.YLim = [0 1];
            app.EzPlot.YLimMode = 'manual';
            app.EzPlot.CLim = [0 1];
            app.EzPlot.CLimMode = 'manual';
            app.EzPlot.XTick = [0 0.5 1];
            app.EzPlot.YTick = [0 0.5 1];
            app.SourceLocationxyLabel = uilabel(app.GUI);
            app.SourceLocationxyLabel.Text = 'Source Location, x & y';
            app.Source_X = uieditfield(app.GUI, 'numeric');
            app.Source_Y = uieditfield(app.GUI, 'numeric');
            app.RectangularScatterDimensionxyLabel = uilabel(app.GUI);
            app.RectangularScatterDimensionxyLabel.Text = 'Rectangular Scatter Dimension, x & y';
            app.ScatterDim_X = uieditfield(app.GUI, 'numeric');
            app.ScatterDim_Y = uieditfield(app.GUI, 'numeric');
            app.TargetLocationxyLabel = uilabel(app.GUI);
            app.TargetLocationxyLabel.Text = 'Target Location, x & y';
            app.Target_X = uieditfield(app.GUI, 'numeric');
            app.Target_Y = uieditfield(app.GUI, 'numeric');
            app.dxEditFieldLabel = uilabel(app.GUI);
            app.dxEditFieldLabel.Text = 'λ /dx';
            app.lambda = uieditfield(app.GUI, 'numeric');
            app.timestepsEditFieldLabel = uilabel(app.GUI);
            app.timestepsEditFieldLabel.Text = 'time steps';
            app.timestepsEditField = uieditfield(app.GUI, 'numeric');
            app.dxEditField = uieditfield(app.GUI, 'numeric');
            app.SetParametersButton = uibutton(app.GUI, 'push');
            app.SetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetParametersButtonPushed, true);
            app.SetParametersButton.Text = 'Set Parameters';
            app.RescaleButton = uibutton(app.GUI, 'push');
            app.RescaleButton.ButtonPushedFcn = createCallbackFcn(app, @RescaleButtonPushed, true);
            app.RescaleButton.Text = 'Rescale';
            app.ExportEzButton = uibutton(app.GUI, 'push');
            app.ExportEzButton.ButtonPushedFcn = createCallbackFcn(app, @ExportEzButtonPushed, true);
            app.ExportEzButton.Text = 'Export Ez';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_2D_FDTD

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUI)
        end
    end
end